classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        watershedsegmentationUIFigure  matlab.ui.Figure
        Label_12                       matlab.ui.control.Label
        Label_11                       matlab.ui.control.Label
        Label_10                       matlab.ui.control.Label
        Image4                         matlab.ui.control.Image
        Image3                         matlab.ui.control.Image
        Image                          matlab.ui.control.Image
        Label_3                        matlab.ui.control.Label
        EditField_2                    matlab.ui.control.NumericEditField
        Label_8                        matlab.ui.control.Label
        EditField_3                    matlab.ui.control.NumericEditField
        Label_9                        matlab.ui.control.Label
        Button_2                       matlab.ui.control.Button
        Button                         matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            % Инициализируем глобальные переменные
            global img;
            % Процесс обработки изображения начнется, если переменная img не будет пуста
            if (~isnan(img))
                if(ndims(img) == 3)
                    % Преобразование изображения в оттенки серого если оно цветное
                    grayImg = rgb2gray(img);
                else
                    % Переменная grayImg есть img, если исходная картинка полутоновая
                    grayImg = img;
                end
                
                % Переменные, вводимые пользователем
                op1 = app.EditField_3.Value;
                m1 = app.EditField_2.Value;
                op = round(op1);
                m = round(m1);
                
                %Gradient Magnitude
                gmag = imgradient(grayImg);
                
                %Opening
                se = strel("disk",op);
               
                %Opening-by-Reconstruction
                Ie = imerode(grayImg,se);
                Iobr = imreconstruct(Ie,grayImg);
                               
                %Opening-Closing by Reconstruction
                Iobrd = imdilate(Iobr,se);
                Iobrcbr = imreconstruct(imcomplement(Iobrd),imcomplement(Iobr));
                Iobrcbr = imcomplement(Iobrcbr);
                
                %Regional Maxima of Opening-Closing by Reconstruction
                fgm = imregionalmax(Iobrcbr);
                
                %Modified Regional Maxima Superimposed on Original Image
                se2 = strel(ones(m,m));
                fgm2 = imclose(fgm,se2);
                fgm3 = imerode(fgm2,se2);
                fgm4 = bwareaopen(fgm3,20);
               
                %Thresholded Opening-Closing by Reconstruction
                bw = imbinarize(Iobrcbr);
                
                %Watershed Ridge Lines
                D = bwdist(bw);
                DL = watershed(D);
                bgm = DL == 0;
                gmag2 = imimposemin(gmag, bgm | fgm4);
                L = watershed(gmag2);

                %Markers and Object Boundaries Superimposed on Original Image
                labels = imdilate(L==0,ones(3,3)) + bgm + fgm4;
                I4 = labeloverlay(grayImg,labels);
                
                %Colored Watershed Label Matrix
                Lrgb = label2rgb(L,"jet","w","shuffle");
                                
                %Colored Labels Superimposed Transparently on Original Image
                app.watershedsegmentationUIFigure.Position= [100, 100, 1500,488];
                app.Image3.ImageSource = Lrgb;
                app.Image4.ImageSource = I4;
                app.Label_3.Text = ('Дело сделано..');
                
            else
                % Если переменная img будет пуста (когда не выбран файл)
                app.Label_3.Text = ('Сначала выберите изображение');
            end

        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
           global img;
            % Переменная ext возвращает значение индекса матрицы разрешенных форматов
            
            [filename, pathname, ext] = uigetfile({'*.*;*.jpg;*.png;*.bmp;*.oct'}, 'Выберите изображение');
            
            % Если выбор изображения был отменен
            if isequal(filename,0) || isequal(pathname,0)
                app.Label_3.Text = ('Изображение не выбрано. Повторите попытку');
                % Разыменование img, поскольку файл не выбран
                img = nan;
                return;
            end
            % Переменная ext должна вернуть индекс элемента из матрицы разрешенных форматов
            if (ext == 1 || ext == 2 || ext == 3)
                fullname = [pathname, filename];
                app.watershedsegmentationUIFigure.Position= [100, 100, 732,488];
                app.Image.ImageSource = fullname;
                img = imread(fullfile(pathname, filename));
                app.Label_3.Text = (['Теперь нажмите' ...
                    ' "Выполнить"']);
                return;
            else
                % При выборе неразрешенного формата ext вернет другое значение
                app.Label_3.Text = ('Выбран неверный тип файла');
                % Разыменование img, поскольку выбран неверный формат
                img = nan;
            end
 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.watershedsegmentationUIFigure = uifigure('Visible', 'off');
            colormap(app.watershedsegmentationUIFigure, 'gray');
            app.watershedsegmentationUIFigure.Name = 'watershed segmentation';
            app.Button = uibutton(app.watershedsegmentationUIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = 'Выполнить';
            app.Button_2 = uibutton(app.watershedsegmentationUIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = 'Выбрать файл';
            app.Label_9 = uilabel(app.watershedsegmentationUIFigure);
            app.Label_9.Text = 'Максимумы';
            app.EditField_3 = uieditfield(app.watershedsegmentationUIFigure, 'numeric');
            app.EditField_3.Value = 6;
            app.Label_8 = uilabel(app.watershedsegmentationUIFigure);
            app.Label_8.Text = 'Открытие';
            app.EditField_2 = uieditfield(app.watershedsegmentationUIFigure, 'numeric');
            app.EditField_2.Value = 4;
            app.Label_3 = uilabel(app.watershedsegmentationUIFigure);
            app.Label_3.Text = '';
            app.Image = uiimage(app.watershedsegmentationUIFigure);
            app.Image3 = uiimage(app.watershedsegmentationUIFigure);
            app.Image4 = uiimage(app.watershedsegmentationUIFigure);
            app.Label_10 = uilabel(app.watershedsegmentationUIFigure);
            app.Label_10.Text = 'Исходное изображение';
            app.Label_11 = uilabel(app.watershedsegmentationUIFigure);
            app.Label_11.Text = 'Изображение водораздела';
            app.Label_12 = uilabel(app.watershedsegmentationUIFigure);
            app.Label_12.Text = 'Изображение со всеми маркерами';
            % Show the figure after all components are created
            app.watershedsegmentationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.watershedsegmentationUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.watershedsegmentationUIFigure)
        end
    end
end